classdef TrussOptimizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunPanel                     matlab.ui.container.Panel
        ModeDropDown                 matlab.ui.control.DropDown
        ModeDropDownLabel            matlab.ui.control.Label
        GensLabel_2                  matlab.ui.control.Label
        ShowEveryEditField           matlab.ui.control.NumericEditField
        ShowEveryEditFieldLabel      matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        EvolveButton                 matlab.ui.control.Button
        ShowButton                   matlab.ui.control.Button
        ShowRankEditField            matlab.ui.control.NumericEditField
        ShowRankEditFieldLabel       matlab.ui.control.Label
        GensLabel                    matlab.ui.control.Label
        StepEditField                matlab.ui.control.NumericEditField
        StepEditFieldLabel           matlab.ui.control.Label
        ConfigurePanel               matlab.ui.container.Panel
        CritFunc2                    matlab.ui.control.NumericEditField
        LengthLabel                  matlab.ui.control.Label
        BudgetEditField              matlab.ui.control.NumericEditField
        BudgetEditFieldLabel         matlab.ui.control.Label
        PerunitlengthEditField       matlab.ui.control.NumericEditField
        PerunitlengthEditFieldLabel  matlab.ui.control.Label
        JointEditField               matlab.ui.control.NumericEditField
        JointEditFieldLabel          matlab.ui.control.Label
        YLimitEditField              matlab.ui.control.NumericEditField
        YLimitLabel                  matlab.ui.control.Label
        XLimitEditField              matlab.ui.control.NumericEditField
        XLimitEditFieldLabel         matlab.ui.control.Label
        LoadYEditField_2             matlab.ui.control.NumericEditField
        LoadYEditField_2Label        matlab.ui.control.Label
        LoadXEditField_2             matlab.ui.control.NumericEditField
        LoadXEditField_2Label        matlab.ui.control.Label
        RollerYEditField_2           matlab.ui.control.NumericEditField
        RollerYEditField_2Label      matlab.ui.control.Label
        RollerXEditField_2           matlab.ui.control.NumericEditField
        RollerXEditField_2Label      matlab.ui.control.Label
        PinYEditField_2              matlab.ui.control.NumericEditField
        PinYEditField_2Label         matlab.ui.control.Label
        PinXEditField_2              matlab.ui.control.NumericEditField
        PinXEditField_2Label         matlab.ui.control.Label
        MaxLengthEditField           matlab.ui.control.NumericEditField
        MaxLengthEditFieldLabel      matlab.ui.control.Label
        MinLengthEditField           matlab.ui.control.NumericEditField
        MinLengthEditFieldLabel      matlab.ui.control.Label
        PopulationEditField          matlab.ui.control.NumericEditField
        PopulationEditFieldLabel     matlab.ui.control.Label
        FlavorText1                  matlab.ui.control.Label
        FlavorText2                  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        CritFunct1                   matlab.ui.control.NumericEditField
        LoadingFunctionLabel         matlab.ui.control.Label
        CostLabel                    matlab.ui.control.Label
        MonitorPanel                 matlab.ui.container.Panel
        Score                        matlab.ui.control.NumericEditField
        Cost                         matlab.ui.control.NumericEditField
        MaxLoad                      matlab.ui.control.NumericEditField
        ScoreLabel                   matlab.ui.control.Label
        CostLabel_2                  matlab.ui.control.Label
        MaxLoadLabel                 matlab.ui.control.Label
        BestTrussLabel               matlab.ui.control.Label
        GeneticTrussOptimizerLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        bool_startedEvolution % Boolean value - Did we start evolution yet?
        env_environment % The environment in question. 
        global_glob % The pin, roller, load, and heat.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.bool_startedEvolution = 0;
            dirCurr = pwd;
            dirTruss = strcat(dirCurr, '/TrussSubfunctions');
            addpath(dirTruss,'-end');
            set(app.ExportButton,'Enable','off')
        end

        % Button pushed function: EvolveButton
        function EvolveButtonPushed(app, event)
            if(app.bool_startedEvolution == 0)
                app.bool_startedEvolution = 1;
                clf
                figure(1)
                app.global_glob = Global([app.PinXEditField_2.Value, app.PinYEditField_2.Value],[app.RollerXEditField_2.Value, app.RollerYEditField_2.Value],[app.LoadXEditField_2.Value, app.LoadYEditField_2.Value]);
                app.env_environment = Environment(app.PopulationEditField.Value, [app.MinLengthEditField.Value, app.MaxLengthEditField.Value], [app.CritFunct1.Value, app.CritFunc2.Value], [app.JointEditField.Value, app.PerunitlengthEditField.Value], app.XLimitEditField.Value, app.YLimitEditField.Value, app.global_glob, app.BudgetEditField.Value, str2double(app.ModeDropDown.Value));
            end
            set(app.EvolveButton,'Enable','off')
            set(app.ShowButton,'Enable','off')
            set(app.ResetButton,'Enable','off')
            % Initial Draw
            app.env_environment.graphRank
            app.MaxLoad.Value = app.env_environment.population(1).flt_maxLoad;
            app.Cost.Value  = app.env_environment.population(1).flt_cost;
            app.Score.Value  = app.env_environment.population(1).flt_score;
            drawnow
            %Stepping and showing every ___ generations
            for i = 1:app.StepEditField.Value
                app.env_environment.stepGen(1);
                if(mod(i,app.ShowEveryEditField.Value) == 0)
                    app.env_environment.graphRank
                    app.MaxLoad.Value = app.env_environment.population(1).flt_maxLoad;
                    app.Cost.Value  = app.env_environment.population(1).flt_cost;
                    app.Score.Value  = app.env_environment.population(1).flt_score;
                    drawnow
                end
            end
            set(app.EvolveButton,'Enable','on')
            set(app.ShowButton,'Enable','on')
            set(app.ResetButton,'Enable','on')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if(app.bool_startedEvolution == 1)
                app.bool_startedEvolution = 0;
                clear app.env_environment;
                clf
                app.MaxLoad.Value = 0;
                app.Cost.Value  = 0;
                app.Score.Value  = 0;
            end
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            app.env_environment.graphRank(app.ShowRankEditField.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeneticTrussOptimizerLabel = uilabel(app.UIFigure);
            app.GeneticTrussOptimizerLabel.Text = 'Genetic Truss Optimizer';
            app.MonitorPanel = uipanel(app.UIFigure);
            app.MonitorPanel.Title = 'Monitor';
            app.BestTrussLabel = uilabel(app.MonitorPanel);
            app.BestTrussLabel.Text = 'Best Truss:';
            app.MaxLoadLabel = uilabel(app.MonitorPanel);
            app.MaxLoadLabel.Text = 'Max Load:';
            app.CostLabel_2 = uilabel(app.MonitorPanel);
            app.CostLabel_2.Text = 'Cost:';
            app.ScoreLabel = uilabel(app.MonitorPanel);
            app.ScoreLabel.Text = 'Score:';
            app.MaxLoad = uieditfield(app.MonitorPanel, 'numeric');
            app.MaxLoad.Editable = 'off';
            app.Cost = uieditfield(app.MonitorPanel, 'numeric');
            app.Cost.Editable = 'off';
            app.Score = uieditfield(app.MonitorPanel, 'numeric');
            app.Score.Editable = 'off';
            app.ConfigurePanel = uipanel(app.UIFigure);
            app.ConfigurePanel.Title = 'Configure';
            app.CostLabel = uilabel(app.ConfigurePanel);
            app.CostLabel.Text = 'Cost';
            app.LoadingFunctionLabel = uilabel(app.ConfigurePanel);
            app.LoadingFunctionLabel.Text = 'Loading Function';
            app.CritFunct1 = uieditfield(app.ConfigurePanel, 'numeric');
            app.CritFunct1.Value = -4338;
            app.Label = uilabel(app.ConfigurePanel);
            app.Label.Text = ')';
            app.FlavorText2 = uilabel(app.ConfigurePanel);
            app.FlavorText2.Text = 'Negative fails in compression. Positive fails in tension,';
            app.FlavorText1 = uilabel(app.ConfigurePanel);
            app.FlavorText1.Text = 'Positive only, keep joints inside the limits. ';
            app.PopulationEditFieldLabel = uilabel(app.ConfigurePanel);
            app.PopulationEditFieldLabel.Text = 'Population';
            app.PopulationEditField = uieditfield(app.ConfigurePanel, 'numeric');
            app.PopulationEditField.Value = 200;
            app.MinLengthEditFieldLabel = uilabel(app.ConfigurePanel);
            app.MinLengthEditFieldLabel.Text = 'Min Length';
            app.MinLengthEditField = uieditfield(app.ConfigurePanel, 'numeric');
            app.MinLengthEditField.Value = 8;
            app.MaxLengthEditFieldLabel = uilabel(app.ConfigurePanel);
            app.MaxLengthEditFieldLabel.Text = 'Max Length';
            app.MaxLengthEditField = uieditfield(app.ConfigurePanel, 'numeric');
            app.MaxLengthEditField.Value = 15;
            app.PinXEditField_2Label = uilabel(app.ConfigurePanel);
            app.PinXEditField_2Label.Text = 'Pin X';
            app.PinXEditField_2 = uieditfield(app.ConfigurePanel, 'numeric');
            app.PinXEditField_2.Value = 10;
            app.PinYEditField_2Label = uilabel(app.ConfigurePanel);
            app.PinYEditField_2Label.Text = 'Pin Y';
            app.PinYEditField_2 = uieditfield(app.ConfigurePanel, 'numeric');
            app.RollerXEditField_2Label = uilabel(app.ConfigurePanel);
            app.RollerXEditField_2Label.Text = 'Roller X';
            app.RollerXEditField_2 = uieditfield(app.ConfigurePanel, 'numeric');
            app.RollerXEditField_2.Value = 40;
            app.RollerYEditField_2Label = uilabel(app.ConfigurePanel);
            app.RollerYEditField_2Label.Text = 'Roller Y';
            app.RollerYEditField_2 = uieditfield(app.ConfigurePanel, 'numeric');
            app.LoadXEditField_2Label = uilabel(app.ConfigurePanel);
            app.LoadXEditField_2Label.Text = 'Load X';
            app.LoadXEditField_2 = uieditfield(app.ConfigurePanel, 'numeric');
            app.LoadXEditField_2.Value = 29;
            app.LoadYEditField_2Label = uilabel(app.ConfigurePanel);
            app.LoadYEditField_2Label.Text = 'Load Y';
            app.LoadYEditField_2 = uieditfield(app.ConfigurePanel, 'numeric');
            app.XLimitEditFieldLabel = uilabel(app.ConfigurePanel);
            app.XLimitEditFieldLabel.Text = 'X Limit';
            app.XLimitEditField = uieditfield(app.ConfigurePanel, 'numeric');
            app.XLimitEditField.Value = 50;
            app.YLimitLabel = uilabel(app.ConfigurePanel);
            app.YLimitLabel.Text = 'Y Limit';
            app.YLimitEditField = uieditfield(app.ConfigurePanel, 'numeric');
            app.YLimitEditField.Value = 30;
            app.JointEditFieldLabel = uilabel(app.ConfigurePanel);
            app.JointEditFieldLabel.Text = 'Joint';
            app.JointEditField = uieditfield(app.ConfigurePanel, 'numeric');
            app.JointEditField.Value = 10;
            app.PerunitlengthEditFieldLabel = uilabel(app.ConfigurePanel);
            app.PerunitlengthEditFieldLabel.Text = 'Per unit length';
            app.PerunitlengthEditField = uieditfield(app.ConfigurePanel, 'numeric');
            app.PerunitlengthEditField.Value = 1;
            app.BudgetEditFieldLabel = uilabel(app.ConfigurePanel);
            app.BudgetEditFieldLabel.Text = 'Budget';
            app.BudgetEditField = uieditfield(app.ConfigurePanel, 'numeric');
            app.BudgetEditField.Value = 325;
            app.LengthLabel = uilabel(app.ConfigurePanel);
            app.LengthLabel.Text = '/ (Length ^';
            app.CritFunc2 = uieditfield(app.ConfigurePanel, 'numeric');
            app.CritFunc2.Value = 2.125;
            app.RunPanel = uipanel(app.UIFigure);
            app.RunPanel.Title = 'Run';
            app.StepEditFieldLabel = uilabel(app.RunPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.RunPanel, 'numeric');
            app.StepEditField.Value = 200;
            app.GensLabel = uilabel(app.RunPanel);
            app.GensLabel.Text = 'Gens';
            app.ShowRankEditFieldLabel = uilabel(app.RunPanel);
            app.ShowRankEditFieldLabel.Text = 'Show Rank';
            app.ShowRankEditField = uieditfield(app.RunPanel, 'numeric');
            app.ShowRankEditField.Value = 1;
            app.ShowButton = uibutton(app.RunPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.EvolveButton = uibutton(app.RunPanel, 'push');
            app.EvolveButton.ButtonPushedFcn = createCallbackFcn(app, @EvolveButtonPushed, true);
            app.EvolveButton.Text = 'Evolve';
            app.ExportButton = uibutton(app.RunPanel, 'push');
            app.ExportButton.Text = 'Export';
            app.ResetButton = uibutton(app.RunPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ShowEveryEditFieldLabel = uilabel(app.RunPanel);
            app.ShowEveryEditFieldLabel.Text = 'Show Every';
            app.ShowEveryEditField = uieditfield(app.RunPanel, 'numeric');
            app.ShowEveryEditField.Value = 25;
            app.GensLabel_2 = uilabel(app.RunPanel);
            app.GensLabel_2.Text = 'Gens';
            app.ModeDropDownLabel = uilabel(app.RunPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.RunPanel);
            app.ModeDropDown.Items = {'Load/Cost', 'Max Load', 'Hybrid'};
            app.ModeDropDown.ItemsData = {'1', '2', '3'};
            app.ModeDropDown.Value = '3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrussOptimizationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end